classdef RoadCrackDetectionApp_v3_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BatchResultHighlightNoteLabel   matlab.ui.control.Label
        BatchIoUDistributionUITable     matlab.ui.control.Table
        BatchIoUDistributionLabel       matlab.ui.control.Label
        BatchBestValueNoteLabel         matlab.ui.control.Label
        BatchResultsUITable             matlab.ui.control.Table
        BatchSummaryUITable             matlab.ui.control.Table
        BatchTitleLabel                 matlab.ui.control.Label
        ErrorMapLegendLabel             matlab.ui.control.Label
        BestValueNoteLabel              matlab.ui.control.Label
        ComparisonUITable               matlab.ui.control.Table
        EvaluationPanel                 matlab.ui.container.Panel
        MetricsStatusLabel              matlab.ui.control.Label
        TimeEditField                   matlab.ui.control.NumericEditField
        AccuracyEditField               matlab.ui.control.NumericEditField
        F1EditField                     matlab.ui.control.NumericEditField
        RecallEditField                 matlab.ui.control.NumericEditField
        PrecisionEditField              matlab.ui.control.NumericEditField
        IoUEditField                    matlab.ui.control.NumericEditField
        TimesLabel                      matlab.ui.control.Label
        AccuracyLabel                   matlab.ui.control.Label
        F1scoreLabel                    matlab.ui.control.Label
        RecallLabel                     matlab.ui.control.Label
        PrecisionLabel                  matlab.ui.control.Label
        IoULabel                        matlab.ui.control.Label
        ControlPanel                    matlab.ui.container.Panel
        BrowseBatchGTButton             matlab.ui.control.Button
        BatchGTFolderEditField          matlab.ui.control.EditField
        GTFolderLabel                   matlab.ui.control.Label
        BrowseBatchOutputButton         matlab.ui.control.Button
        BatchOutputFolderEditField      matlab.ui.control.EditField
        OutputFolderLabel               matlab.ui.control.Label
        BatchStatusLabel                matlab.ui.control.Label
        RunBatchButton                  matlab.ui.control.Button
        BrowseBatchImagesButton         matlab.ui.control.Button
        BatchImageFolderEditField       matlab.ui.control.EditField
        BatchImageFolderEditFieldLabel  matlab.ui.control.Label
        CompareViewDropDown             matlab.ui.control.DropDown
        ResultViewDropDownLabel         matlab.ui.control.Label
        LoadImageButton                 matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        DetectionModeButtonGroup        matlab.ui.container.ButtonGroup
        BatchButton                     matlab.ui.control.ToggleButton
        CompareButton                   matlab.ui.control.ToggleButton
        SingleButton                    matlab.ui.control.ToggleButton
        StatusLamp                      matlab.ui.control.Lamp
        StatusLabel                     matlab.ui.control.Label
        SaveResultsButton               matlab.ui.control.Button
        RunDetectionButton              matlab.ui.control.Button
        ApplyPostprocessingCheckBox     matlab.ui.control.CheckBox
        DetectionMethodDropDown         matlab.ui.control.DropDown
        DetectionMethodDropDownLabel    matlab.ui.control.Label
        UNetUIAxes                      matlab.ui.control.UIAxes
        CannyUIAxes                     matlab.ui.control.UIAxes
        SauvolaUIAxes                   matlab.ui.control.UIAxes
        OtsuUIAxes                      matlab.ui.control.UIAxes
        OverlayUIAxes                   matlab.ui.control.UIAxes
        GroundTruthUIAxes               matlab.ui.control.UIAxes
        FinalMaskUIAxes                 matlab.ui.control.UIAxes
        OriginalUIAxes                  matlab.ui.control.UIAxes
        RawMaskUIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CurrentImage

        RawMask
        FinalMask

        CompareMasks
        CompareTimes
        CompareMetricsTable
        CompareRGBImage

        SingleResultsValid = false
        CompareResultsValid = false

        CurrentImagePath

        GroundTruthMask
        GroundTruthPath
        
        StatusResetTimer

        BatchImageFolder
        BatchGroundTruthFolder
        BatchOutputFolder

        BatchIoUDistributionTable
        BatchSummaryTable
        BatchResultsTable

        BatchIsRunning = false

        % Folder containing masks from the latest Batch run
        BatchLastRunFolder = ''

        % Post-processing setting used by the latest Batch run
        BatchPostprocessingApplied = false

        Config

    end
    
    methods (Access = private)
        
        function cleanup(app)

            % Delete timer
            if ~isempty(app.StatusResetTimer) && ...
                    isvalid(app.StatusResetTimer)

                stop(app.StatusResetTimer);
                delete(app.StatusResetTimer);
                app.StatusResetTimer = [];

            end

        end
        
        %%
        function setSingleResultsVisible(app, state)

            axesList = [ app.RawMaskUIAxes, app.FinalMaskUIAxes, app.OverlayUIAxes];

            for ax = axesList
                ax.Visible = state;
                ax.Title.Visible = state;

                if ~isempty(ax.Children)
                    set(ax.Children, 'Visible', state);
                end
            end

        end

        %% 
        function setComparisonResultsVisible(app, state)

            axesList = [ ...
                app.OtsuUIAxes, ...
                app.SauvolaUIAxes, ...
                app.CannyUIAxes, ...
                app.UNetUIAxes];

            for ax = axesList
                ax.Visible = state;
                ax.Title.Visible = state;

                if ~isempty(ax.Children)
                    set(ax.Children, 'Visible', state);
                end
            end

            app.ComparisonUITable.Visible = state;

        end

        %%
        function scheduleStatusReset(app)

            % Stop and delete the previous timer
            if ~isempty(app.StatusResetTimer) && ...
                    isvalid(app.StatusResetTimer)

                stop(app.StatusResetTimer);
                delete(app.StatusResetTimer);
            end 

            % Create a new one-shot timer
            app.StatusResetTimer = timer( 'ExecutionMode', 'singleShot', ...
                'StartDelay', 2, 'TimerFcn', @(~, ~) app.resetStatusToReady());

            start(app.StatusResetTimer);
        end

        
        %%
        function resetStatusToReady(app)

            if isvalid(app) && ~isempty(app.UIFigure) && isvalid(app.UIFigure)

                app.StatusLamp.Color = [0.65 0.65 0.65];
                app.StatusLabel.Text = 'Ready';
            end
        end

        %% run sin
        function runSingleDetection(app, rgbImage, grayImage)

            % Read selected method
            methodName = app.DetectionMethodDropDown.Value;

            % Run selected detection method
            switch methodName

                case {'Otsu', 'Sauvola', 'Canny'}

                    rawMask = detectClassical( grayImage, methodName, app.Config);

                case 'U-Net'

                    rawMask = inferUNet( rgbImage, app.Config);

                otherwise

                    error( 'AppDetection:UnknownMethod', ...
                        'Unsupported detection method: %s', ...
                        methodName);

            end

            app.RawMask = logical(rawMask);

            % Display raw result
            imshow(app.RawMask, 'Parent', app.RawMaskUIAxes);
            title(app.RawMaskUIAxes, [methodName, ' Raw Result']);

            axis(app.RawMaskUIAxes, 'image');
            axis(app.RawMaskUIAxes, 'off');

            % Optional post-processing
            if app.ApplyPostprocessingCheckBox.Value

                finalMask = postprocessMask( app.RawMask, methodName, app.Config, grayImage);

            else

                finalMask = app.RawMask;

            end

            app.FinalMask = logical(finalMask);

            % Display final result
            imshow(app.FinalMask, 'Parent', app.FinalMaskUIAxes);

            if app.ApplyPostprocessingCheckBox.Value

                title( app.FinalMaskUIAxes, ...
                    [methodName, ' Post-processed Result']);

            else

                title( app.FinalMaskUIAxes, ...
                    [methodName, ' Final Result']);

            end

            axis(app.FinalMaskUIAxes, 'image');
            axis(app.FinalMaskUIAxes, 'off');

            % Create and display overlay
            overlayImage = createOverlay( rgbImage, app.FinalMask, app.Config);

            imshow(overlayImage, 'Parent', app.OverlayUIAxes);
            title(app.OverlayUIAxes, [methodName, ' Overlay Result']);

            axis(app.OverlayUIAxes, 'image');
            axis(app.OverlayUIAxes, 'off');

            % Calculate metrics
            if ~isempty(app.GroundTruthMask)

                metrics = computeMetrics( app.FinalMask, ...
                    app.GroundTruthMask, app.Config.metricTolerance);

                app.IoUEditField.Value = metrics.IoU;
                app.PrecisionEditField.Value = metrics.Precision;
                app.RecallEditField.Value = metrics.Recall;
                app.F1EditField.Value = metrics.F1Score;
                app.AccuracyEditField.Value = metrics.Accuracy;

                if app.ApplyPostprocessingCheckBox.Value
                    app.MetricsStatusLabel.Text = ...
                        'Metrics for Post-processed Result';
                else
                    app.MetricsStatusLabel.Text = ...
                        'Metrics for Raw Result';
                end

            else

                app.IoUEditField.Value = NaN;
                app.PrecisionEditField.Value = NaN;
                app.RecallEditField.Value = NaN;
                app.F1EditField.Value = NaN;
                app.AccuracyEditField.Value = NaN;

                app.MetricsStatusLabel.Text = 'Ground Truth unavailable';

            end

            % Mark Single results as valid
            app.SingleResultsValid = true;

        end

        %% com
        function runComparison(app, rgbImage, grayImage)

            methodNames = {'Otsu', 'Sauvola', 'Canny', 'U-Net'};
            masks = cell(1, 4);
            times = zeros(1, 4);

            % Classical methods

            for k = 1:3

                methodName = methodNames{k};
                startTime = tic;

                mask = detectClassical( grayImage, methodName, app.Config);

                if app.ApplyPostprocessingCheckBox.Value
                    mask = postprocessMask( mask, methodName, app.Config, grayImage);
                end

                masks{k} = logical(mask);
                times(k) = toc(startTime);

            end

            % U-Net

            startTime = tic;

            mask = inferUNet(rgbImage, app.Config);

            if app.ApplyPostprocessingCheckBox.Value
                mask = postprocessMask( mask, 'U-Net', app.Config, grayImage);
            end

            masks{4} = logical(mask);
            times(4) = toc(startTime);

            % Store results

            app.CompareMasks = masks;
            app.CompareTimes = times;
            app.CompareRGBImage = rgbImage;

            % Display results or overlays
            app.updateComparisonDisplay();

            % Update comparison table
            app.updateComparisonTable();

            % Mark Compare results as valid
            app.CompareResultsValid = true;

        end

        %% Display current comparison view
        function updateComparisonDisplay(app)

            % Check comparison results
            if ~iscell(app.CompareMasks) || ...
                    numel(app.CompareMasks) ~= 4 || ...
                    any(cellfun(@isempty, app.CompareMasks))

                return;
            end

            viewMode = app.CompareViewDropDown.Value;

            displayImages = cell(1, 4);

            % Generate display images
            for k = 1:4

                switch viewMode

                    case 'Mask'

                        displayImages{k} = app.CompareMasks{k};

                    case 'Overlay'

                        if isempty(app.CompareRGBImage)
                            return;
                        end

                        displayImages{k} = createOverlay( ...
                            app.CompareRGBImage, ...
                            app.CompareMasks{k}, ...
                            app.Config);

                    case 'Error Map'

                        if isempty(app.GroundTruthMask)
                            return;
                        end

                        displayImages{k} = app.createErrorMap( ...
                            app.CompareMasks{k}, ...
                            app.GroundTruthMask);

                    otherwise

                    displayImages{k} = app.CompareMasks{k};

                end

            end

            % Otsu
            imshow(displayImages{1}, 'Parent', app.OtsuUIAxes);
            title(app.OtsuUIAxes, 'Otsu');
            axis(app.OtsuUIAxes, 'image');
            axis(app.OtsuUIAxes, 'off');

            % Sauvola
            imshow(displayImages{2}, 'Parent', app.SauvolaUIAxes);
            title(app.SauvolaUIAxes, 'Sauvola');
            axis(app.SauvolaUIAxes, 'image');
            axis(app.SauvolaUIAxes, 'off');

            % Canny
            imshow(displayImages{3}, 'Parent', app.CannyUIAxes);
            title(app.CannyUIAxes, 'Canny');
            axis(app.CannyUIAxes, 'image');
            axis(app.CannyUIAxes, 'off');

            % U-Net
            imshow(displayImages{4}, 'Parent', app.UNetUIAxes);
            title(app.UNetUIAxes, 'U-Net');
            axis(app.UNetUIAxes, 'image');
            axis(app.UNetUIAxes, 'off');

        end

        %% Error Map
        function errorMap = createErrorMap(~, predictionMask, groundTruthMask) 

            predictionMask = logical(predictionMask);
            groundTruthMask = logical(groundTruthMask);

            % Match mask sizes if necessary
            if ~isequal(size(predictionMask), size(groundTruthMask))

                predictionMask = imresize( predictionMask, ...
                    size(groundTruthMask), 'nearest');

            end

            % Classification regions
            truePositive  = predictionMask & groundTruthMask;
            falsePositive = predictionMask & ~groundTruthMask;
            falseNegative = ~predictionMask & groundTruthMask;

            % RGB error map
            errorMap = zeros( size(groundTruthMask, 1), size(groundTruthMask, 2), 3);

            % Red channel: false positive
            errorMap(:, :, 1) = falsePositive;

            % Green channel: true positive
            errorMap(:, :, 2) = truePositive;

            % Blue channel: false negative
            errorMap(:, :, 3) = falseNegative;

        end

        %% 重置sin结果
        function resetSingleResults(app)

            app.SingleResultsValid = false;
            
            % Clear stored masks
            app.RawMask = [];
            app.FinalMask = [];

            % Clear axes
            cla(app.RawMaskUIAxes);
            cla(app.FinalMaskUIAxes);
            cla(app.OverlayUIAxes);

            % Restore titles
            title(app.RawMaskUIAxes, 'Raw Result');
            title(app.FinalMaskUIAxes, 'Post-processed Result');
            title(app.OverlayUIAxes, 'Overlay Result');

            % Remove ticks and labels
            axesList = [ ...
                app.RawMaskUIAxes, ...
                app.FinalMaskUIAxes, ...
                app.OverlayUIAxes];

            for ax = axesList
                ax.XTick = [];
                ax.YTick = [];
                ax.XLabel.String = '';
                ax.YLabel.String = '';
            end

            % Reset evaluation metrics
            app.IoUEditField.Value = 0;
            app.PrecisionEditField.Value = 0;
            app.RecallEditField.Value = 0;
            app.F1EditField.Value = 0;
            app.AccuracyEditField.Value = 0;
            app.TimeEditField.Value = 0;

            app.MetricsStatusLabel.Text = 'Metrics for Final Result';

        end

        %% 重置com结果
        function resetComparisonResults(app)

            app.CompareResultsValid = false;
            
            % Clear stored comparison data
            app.CompareMasks = [];
            app.CompareTimes = [];
            app.CompareMetricsTable = table();
            app.CompareRGBImage = [];
            app.CompareViewDropDown.Value = 'Mask';
            app.ErrorMapLegendLabel.Visible = 'off';

            % Clear comparison axes
            cla(app.OtsuUIAxes);
            cla(app.SauvolaUIAxes);
            cla(app.CannyUIAxes);
            cla(app.UNetUIAxes);

            % Restore titles
            title(app.OtsuUIAxes, 'Otsu');
            title(app.SauvolaUIAxes, 'Sauvola');
            title(app.CannyUIAxes, 'Canny');
            title(app.UNetUIAxes, 'U-Net');

            % Remove ticks and labels
            axesList = [ ...
                app.OtsuUIAxes, ...
                app.SauvolaUIAxes, ...
                app.CannyUIAxes, ...
                app.UNetUIAxes];

            for ax = axesList
                ax.XTick = [];
                ax.YTick = [];
                ax.XLabel.String = '';
                ax.YLabel.String = '';
            end

            % Clear previous table styles
            removeStyle(app.ComparisonUITable);
            
            % Reset comparison table
            app.ComparisonUITable.Data = { ...
                'Otsu',    '—', '—', '—', '—', '—'; ...
                'Sauvola', '—', '—', '—', '—', '—'; ...
                'Canny',   '—', '—', '—', '—', '—'; ...
                'U-Net',   '—', '—', '—', '—', '—'};

        end

        %% 保留三位小数
        function text = formatMetric(~, value) 

            if isnan(value)
                text = 'N/A';
            else
                text = sprintf('%.3f', value);
            end

        end
        
        %% 结果过期提示
        function markCurrentResultsOutdated(app)

            if app.SingleButton.Value
                app.SingleResultsValid = false;
            else
                app.CompareResultsValid = false;
            end

            app.SaveResultsButton.Enable = 'off';

            app.StatusLamp.Color = [1.00 0.65 0.00];
            app.StatusLabel.Text = 'Rerun required';

            app.scheduleStatusReset();

        end

        %% 保存sin结果
        function saveSingleResults(app, outputFolder, baseName)
            
            methodName = app.DetectionMethodDropDown.Value;
            safeMethodName = matlab.lang.makeValidName(methodName);

            filePrefix = sprintf( '%s_%s', baseName, safeMethodName);
            
            originalPath = fullfile( outputFolder, ...
                [filePrefix, '_01_original.png']);

            % Save original image
            imwrite( app.CurrentImage, originalPath);

            % Save ground truth if available

            if ~isempty(app.GroundTruthMask)

                groundTruthPath = fullfile( outputFolder, ...
                    [filePrefix, '_02_ground_truth.png']);

                imwrite( logical(app.GroundTruthMask), ...
                    groundTruthPath);
            end

            % Save raw mask

            rawMaskPath = fullfile( outputFolder, ...
                [filePrefix, '_03_raw_mask.png']);

            imwrite( logical(app.RawMask), rawMaskPath);

            % Save final mask

            finalMaskPath = fullfile( outputFolder, ...
                [filePrefix, '_04_final_mask.png']);

            imwrite( logical(app.FinalMask), finalMaskPath);

            % Recreate and save overlay using shared modules

            [rgbImage, ~] = preprocessImage( app.CurrentImage, app.Config);

            overlayImage = createOverlay( rgbImage, app.FinalMask, app.Config);

            overlayPath = fullfile( outputFolder, ...
                [filePrefix, '_05_overlay.png']);

            imwrite( overlayImage, overlayPath);

            % Save metrics CSV

            metricsTable = table( ...
                app.IoUEditField.Value, ...
                app.PrecisionEditField.Value, ...
                app.RecallEditField.Value, ...
                app.F1EditField.Value, ...
                app.AccuracyEditField.Value, ...
                app.TimeEditField.Value, ...
                string(methodName), ...
                logical(app.ApplyPostprocessingCheckBox.Value), ...
                string(app.CurrentImagePath), ...
                string(app.GroundTruthPath), ...
                'VariableNames', { ...
                'IoU', ...
                'Precision', ...
                'Recall', ...
                'F1Score', ...
                'Accuracy', ...
                'ProcessingTimeSeconds', ...
                'Method', ...
                'PostprocessingApplied', ...
                'InputImagePath', ...
                'GroundTruthPath'});

            metricsPath = fullfile( outputFolder, ...
                [filePrefix, '_06_metrics.csv']);

            writetable( metricsTable, metricsPath);

            % Save App screenshot

            screenshotPath = fullfile( outputFolder, ...
                [filePrefix, '_07_app_screenshot.png']);

            if ~isfield(app.Config, 'saveAppScreenshot') || ...
                    app.Config.saveAppScreenshot

                exportapp( app.UIFigure, screenshotPath);
            end
        end
            
        %% 保存compare结果
        function saveComparisonResults(app, outputFolder, baseName)

            if isempty(app.CompareMetricsTable) || ...
                    height(app.CompareMetricsTable) ~= 4

                error( 'AppSave:InvalidComparisonMetrics', ...
                    'Comparison metrics are unavailable. Please run comparison again.');
            end

            filePrefix = sprintf('%s_Compare', baseName);
            methodNames = {'Otsu', 'Sauvola', 'Canny', 'UNet'};

            % Save four masks
            for k = 1:4

                maskPath = fullfile( outputFolder, ...
                    sprintf( '%s_%02d_%s_mask.png', ...
                    filePrefix, k, methodNames{k}));

                imwrite(logical(app.CompareMasks{k}), maskPath);
            end

            % Save comparison metrics CSV
            metricsTable = app.CompareMetricsTable;

            rowCount = height(metricsTable);

            metricsTable.PostprocessingApplied = repmat( ...
                logical(app.ApplyPostprocessingCheckBox.Value), ...
                rowCount, 1);

            metricsTable.InputImagePath = repmat( ...
                string(app.CurrentImagePath), ...
                rowCount, 1);

            metricsTable.GroundTruthPath = repmat( ...
                string(app.GroundTruthPath), ...
                rowCount, 1);

            metricsPath = fullfile( outputFolder, ...
                [filePrefix, '_05_comparison_metrics.csv']);

            writetable(metricsTable, metricsPath);

            % Save App screenshot

            if ~isfield(app.Config, 'saveAppScreenshot') || ...
                    app.Config.saveAppScreenshot

                screenshotPath = fullfile( outputFolder, ...
                    [filePrefix, '_06_app_screenshot.png']);

                exportapp(app.UIFigure, screenshotPath);
            end

        end

        %% 更新com表格
        function updateComparisonTable(app)

            methodNames = ["Otsu"; "Sauvola"; "Canny"; "U-Net"];

            precision = nan(4, 1);
            recall = nan(4, 1);
            f1 = nan(4, 1);
            iou = nan(4, 1);

            % Calculate metrics if ground truth is available
            if ~isempty(app.GroundTruthMask)

                for k = 1:4

                    metrics = computeMetrics( app.CompareMasks{k}, ...
                        app.GroundTruthMask, app.Config.metricTolerance);

                    precision(k) = metrics.Precision;
                    recall(k) = metrics.Recall;
                    f1(k) = metrics.F1Score;
                    iou(k) = metrics.IoU;

                end

            end

            % Store numeric results for CSV
            app.CompareMetricsTable = table( ...
                methodNames, ...
                precision, ...
                recall, ...
                f1, ...
                iou, ...
                app.CompareTimes(:), ...
                'VariableNames', { ...
                'Method', ...
                'Precision', ...
                'Recall', ...
                'F1', ...
                'IoU', ...
                'TimeSeconds'});

            % Display exactly three decimal places
            app.ComparisonUITable.Data = { ...
                char(methodNames(1)), app.formatMetric(precision(1)), ...
                app.formatMetric(recall(1)), app.formatMetric(f1(1)), ...
                app.formatMetric(iou(1)), sprintf('%.3f', app.CompareTimes(1));

                char(methodNames(2)), app.formatMetric(precision(2)), ...
                app.formatMetric(recall(2)), app.formatMetric(f1(2)), ...
                app.formatMetric(iou(2)), sprintf('%.3f', app.CompareTimes(2));

                char(methodNames(3)), app.formatMetric(precision(3)), ...
                app.formatMetric(recall(3)), app.formatMetric(f1(3)), ...
                app.formatMetric(iou(3)), sprintf('%.3f', app.CompareTimes(3));

                char(methodNames(4)), app.formatMetric(precision(4)), ...
                app.formatMetric(recall(4)), app.formatMetric(f1(4)), ...
                app.formatMetric(iou(4)), sprintf('%.3f', app.CompareTimes(4))};

            % Remove previous styles
            removeStyle(app.ComparisonUITable);

            % Highlight best values in red
            bestStyle = uistyle( 'FontColor', [0.85 0.10 0.10]);

            % Metric columns: maximum value is best
            metricData = [ precision, recall, f1, iou ];

            for columnIndex = 1:size(metricData, 2)

                columnValues = metricData(:, columnIndex);

                if all(isnan(columnValues))
                    continue;
                end

                bestValue = max(columnValues, [], 'omitnan');

                bestRows = find(columnValues == bestValue);

                % Table columns 2–6 contain the metrics
                addStyle( app.ComparisonUITable, bestStyle, 'cell', ...
                    [bestRows, repmat(columnIndex + 1, numel(bestRows), 1)]);
            end

            % Time column: minimum value is best
            timeValues = app.CompareTimes(:);

            if ~all(isnan(timeValues))

                bestTime = min(timeValues, [], 'omitnan');
                bestTimeRows = find(timeValues == bestTime);

                addStyle( app.ComparisonUITable, bestStyle, 'cell', ...
                    [bestTimeRows, repmat(6, numel(bestTimeRows), 1)]);
            end

        end

        %% 查找图片
        function imageFiles = findBatchImageFiles(app)

            if isempty(app.BatchImageFolder) || ...
                    ~isfolder(app.BatchImageFolder)

                error( 'Batch:InvalidImageFolder', ...
                    'The batch image folder is invalid.');
            end

            extensions = {'*.jpg', '*.jpeg'};

            fileStruct = [];

            for k = 1:numel(extensions)

                files = dir(fullfile( app.BatchImageFolder, extensions{k}));

                fileStruct = [fileStruct; files]; %#ok<AGROW>

            end

            if isempty(fileStruct)

                error( 'Batch:NoImages', 'No supported image files were found.');
            end

            % Sort by file name
            [~, order] = sort(lower({fileStruct.name}));
            fileStruct = fileStruct(order);

            imageFiles = fullfile( {fileStruct.folder}, {fileStruct.name});

        end

        %% 匹配GT
        function gtPath = findBatchGroundTruth(app, imagePath)

            gtPath = '';

            if isempty(app.BatchGroundTruthFolder) || ...
                    ~isfolder(app.BatchGroundTruthFolder)

                return;
            end

            [~, baseName, ~] = fileparts(imagePath);

            candidateNames = { [baseName, '.png'], ...
                [baseName, '_mask.png']};

            for candidateIndex = 1:numel(candidateNames)

                candidatePath = fullfile( ...
                    app.BatchGroundTruthFolder, ...
                    candidateNames{candidateIndex});

                if isfile(candidatePath)

                    gtPath = candidatePath;
                    return;

                end
            end
        end

        %% Batch 扫描
        function scanBatchFolders(app)

            imageFiles = app.findBatchImageFiles();

            totalImages = numel(imageFiles);
            gtCount = 0;

            previewData = cell(totalImages, 8);

            for imageIndex = 1:totalImages

                imagePath = imageFiles{imageIndex};
                gtPath = app.findBatchGroundTruth(imagePath);

                [~, fileName, extension] = fileparts(imagePath);
                displayName = [fileName, extension];

                if ~isempty(gtPath)
                    gtCount = gtCount + 1;
                    statusText = 'Ready';
                else
                    statusText = 'No GT';
                end

                previewData(imageIndex, :) = { ...
                    displayName, ...
                    '—', ...
                    '—', ...
                    '—', ...
                    '—', ...
                    '—', ...
                    '—', ...
                    statusText};

            end

            app.BatchResultsUITable.Data = previewData;

            app.BatchStatusLabel.Text = sprintf( ...
                '%d images, %d with GT', ...
                totalImages, gtCount);

        end

        %% buildBatchSummary
        function summaryTable = buildBatchSummary(~, resultsTable)

            methodNames = ["Otsu"; "Sauvola"; "Canny"; "U-Net"];

            methodColumn = strings(4, 1);
            imageCount = zeros(4, 1);
            withGTCount = zeros(4, 1);

            meanPrecision = nan(4, 1);
            meanRecall = nan(4, 1);
            meanF1 = nan(4, 1);
            meanIoU = nan(4, 1);
            stdIoU = nan(4, 1);
            meanTime = nan(4, 1);

            failureCount = zeros(4, 1);

            for methodIndex = 1:4

                methodName = methodNames(methodIndex);
                methodColumn(methodIndex) = methodName;

                methodRows = resultsTable.Method == methodName;

                currentResults = resultsTable(methodRows, :);

                imageCount(methodIndex) = height(currentResults);

                successfulRows = ...
                    currentResults.Status == "Success" | ...
                    currentResults.Status == "No GT";

                failureCount(methodIndex) = sum( ...
                    currentResults.Status == "Failed");

                withGTRows = successfulRows & ...
                    ~isnan(currentResults.IoU);

                withGTCount(methodIndex) = sum(withGTRows);

                if any(withGTRows)

                    meanPrecision(methodIndex) = mean( ...
                        currentResults.Precision(withGTRows), 'omitnan');

                    meanRecall(methodIndex) = mean( ...
                        currentResults.Recall(withGTRows), 'omitnan');

                    meanF1(methodIndex) = mean( ...
                        currentResults.F1(withGTRows), 'omitnan');

                    meanIoU(methodIndex) = mean( ...
                        currentResults.IoU(withGTRows), 'omitnan');

                    stdIoU(methodIndex) = std( ...
                        currentResults.IoU(withGTRows), 'omitnan');

                end

                validTimeRows = successfulRows & ...
                    ~isnan(currentResults.TimeSeconds);

                if any(validTimeRows)

                    meanTime(methodIndex) = mean( ...
                        currentResults.TimeSeconds(validTimeRows), ...
                        'omitnan');

                end

            end

            summaryTable = table( ...
                methodColumn, ...
                imageCount, ...
                withGTCount, ...
                meanPrecision, ...
                meanRecall, ...
                meanF1, ...
                meanIoU, ...
                stdIoU, ...
                meanTime, ...
                failureCount, ...
                'VariableNames', { ...
                'Method', ...
                'Images', ...
                'WithGT', ...
                'MeanPrecision', ...
                'MeanRecall', ...
                'MeanF1', ...
                'MeanIoU', ...
                'StdIoU', ...
                'MeanTimeSeconds', ...
                'Failures'});

        end

        %% Batch 表格显示3 位小数，无 GT 时显示 N/A
        function updateBatchTables(app)

            results = app.BatchResultsTable;
            summary = app.BatchSummaryTable;

            % Per-image results
            resultData = cell(height(results), 8);

            for rowIndex = 1:height(results)

                resultData(rowIndex, :) = { ...
                    char(results.Image(rowIndex)), ...
                    char(results.Method(rowIndex)), ...
                    app.formatMetric(results.Precision(rowIndex)), ...
                    app.formatMetric(results.Recall(rowIndex)), ...
                    app.formatMetric(results.F1(rowIndex)), ...
                    app.formatMetric(results.IoU(rowIndex)), ...
                    app.formatMetric(results.TimeSeconds(rowIndex)), ...
                    char(results.Status(rowIndex))};

            end

            app.BatchResultsUITable.Data = resultData;

            % Summary results
            summaryData = cell(height(summary), 10);

            for rowIndex = 1:height(summary)

                summaryData(rowIndex, :) = { ...
                    char(summary.Method(rowIndex)), ...
                    summary.Images(rowIndex), ...
                    summary.WithGT(rowIndex), ...
                    app.formatMetric(summary.MeanPrecision(rowIndex)), ...
                    app.formatMetric(summary.MeanRecall(rowIndex)), ...
                    app.formatMetric(summary.MeanF1(rowIndex)), ...
                    app.formatMetric(summary.MeanIoU(rowIndex)), ...
                    app.formatMetric(summary.StdIoU(rowIndex)), ...
                    app.formatMetric(summary.MeanTimeSeconds(rowIndex)), ...
                    summary.Failures(rowIndex)};

            end

            app.BatchSummaryUITable.Data = summaryData;


            % Per-image table: global best values red, zeros blue
            app.highlightBatchResultValues();
            
            % Highlight the best summary values
            app.highlightBestBatchSummary();

            % DistributionTable
            distribution = app.BatchIoUDistributionTable;
            distributionData = cell(height(distribution), 11);

            for rowIndex = 1:height(distribution)

                distributionData(rowIndex, :) = { ...
                    char(distribution.Method(rowIndex)), ...
                    distribution.IoU_0_01(rowIndex), ...
                    distribution.IoU_01_02(rowIndex), ...
                    distribution.IoU_02_03(rowIndex), ...
                    distribution.IoU_03_04(rowIndex), ...
                    distribution.IoU_04_05(rowIndex), ...
                    distribution.IoU_05_06(rowIndex), ...
                    distribution.IoU_06_07(rowIndex), ...
                    distribution.IoU_07_08(rowIndex), ...
                    distribution.IoU_08_09(rowIndex), ...
                    distribution.IoU_09_10(rowIndex)};

            end

            app.BatchIoUDistributionUITable.Data = distributionData;

        end

        %% batch处理单张图
        function [resultRows, resultMasks] = processBatchImage(app, imagePath, gtPath)

            methodNames = {'Otsu', 'Sauvola', 'Canny', 'U-Net'};

            % Read and preprocess image once
            inputImage = imread(imagePath);

            [rgbImage, grayImage] = preprocessImage( ...
                inputImage, app.Config);

            % Read Ground Truth
            hasGT = ~isempty(gtPath) && isfile(gtPath);

            if hasGT

                gtRaw = imread(gtPath);

                if size(gtRaw, 3) == 3
                    gtRaw = rgb2gray(gtRaw);
                end

                gtMask = gtRaw > 0;

                if ~isequal(size(gtMask), size(grayImage))
                    gtMask = imresize( gtMask, ...
                        size(grayImage), 'nearest');
                end

                gtMask = logical(gtMask);

            else

                gtMask = [];

            end

            [~, imageName, imageExtension] = fileparts(imagePath);
            displayName = [imageName, imageExtension];

            % One row for each method
            resultRows = cell(4, 8);

            % Final mask generated by each method
            resultMasks = cell(1, 4);

            for methodIndex = 1:4

                methodName = methodNames{methodIndex};

                precision = NaN;
                recall = NaN;
                f1 = NaN;
                iou = NaN;
                elapsedTime = NaN;
                statusText = 'Success';

                try

                    methodTimer = tic;

                    switch methodName

                        case {'Otsu', 'Sauvola', 'Canny'}

                            mask = detectClassical( grayImage, methodName, app.Config);

                        case 'U-Net'

                            mask = inferUNet( rgbImage, app.Config);

                    end

                    if app.ApplyPostprocessingCheckBox.Value

                        mask = postprocessMask( mask, methodName, app.Config, grayImage);

                    end

                    mask = logical(mask);

                    % Store the final mask for saving
                    resultMasks{methodIndex} = mask;

                    elapsedTime = toc(methodTimer);

                    if hasGT

                        metrics = computeMetrics(mask, ...
                            gtMask, app.Config.metricTolerance);

                        precision = metrics.Precision;
                        recall = metrics.Recall;
                        f1 = metrics.F1Score;
                        iou = metrics.IoU;

                    else

                        statusText = 'No GT';

                    end

                catch ME

                    statusText = 'Failed';
                    resultMasks{methodIndex} = [];

                    warning( ...
                        'Failed: image=%s, method=%s\n%s', ...
                        displayName, ...
                        methodName, ...
                        getReport(ME, 'extended', 'hyperlinks', 'off'));

                end

                resultRows(methodIndex, :) = { ...
                    displayName, ...
                    methodName, ...
                    precision, ...
                    recall, ...
                    f1, ...
                    iou, ...
                    elapsedTime, ...
                    statusText};

            end

        end

        %% Save four masks for one batch image
        function saveBatchMasks(~, resultMasks, imagePath, savedFolder)

            if isempty(resultMasks)
                return;
            end

            [~, baseName, ~] = fileparts(imagePath);

            folderNames = { ...
                'Otsu', ...
                'Sauvola', ...
                'Canny', ...
                'UNet'};

            fileMethodNames = { ...
                'Otsu', ...
                'Sauvola', ...
                'Canny', ...
                'UNet'};

            for methodIndex = 1:4

                currentMask = resultMasks{methodIndex};

                % A failed method has no mask to save
                if isempty(currentMask)
                    continue;
                end

                methodFolder = fullfile( savedFolder, 'masks', ...
                    folderNames{methodIndex});

                if ~isfolder(methodFolder)
                    mkdir(methodFolder);
                end

                maskFileName = sprintf( '%s_%s_mask.png', ...
                    baseName, fileMethodNames{methodIndex});

                maskPath = fullfile( methodFolder, maskFileName);

                imwrite(logical(currentMask), maskPath);

            end

        end

        %% 批量运行
        function wasCancelled = runBatchProcessing(app, savedFolder)

            wasCancelled = false;

            imageFiles = app.findBatchImageFiles();

            totalImages = numel(imageFiles);
            allRows = cell(totalImages * 4, 8);

            currentRow = 1;

            progressDialog = uiprogressdlg( ...
                app.UIFigure, ...
                'Title', 'Batch Processing', ...
                'Message', 'Preparing...', ...
                'Cancelable', 'on');

            cleanupProgress = onCleanup(@() delete(progressDialog));

            for imageIndex = 1:totalImages

                if progressDialog.CancelRequested

                    wasCancelled = true;
                    break;

                end

                imagePath = imageFiles{imageIndex};
                gtPath = app.findBatchGroundTruth(imagePath);

                [~, fileName, extension] = fileparts(imagePath);

                progressDialog.Value = imageIndex / totalImages;
                progressDialog.Message = sprintf( ...
                    'Processing %d of %d: %s', ...
                    imageIndex, ...
                    totalImages, ...
                    [fileName, extension]);

                drawnow;

                [rows, resultMasks] = app.processBatchImage( ...
                    imagePath, ...
                    gtPath);

                % Save four masks immediately after processing this image
                app.saveBatchMasks( ...
                    resultMasks, ...
                    imagePath, ...
                    savedFolder);

                allRows(currentRow:currentRow + 3, :) = rows;

                currentRow = currentRow + 4;

                % Show results progressively
                if mod(imageIndex, 5) == 0 || imageIndex == totalImages

                    app.BatchResultsUITable.Data = allRows(1:currentRow - 1, :);

                    drawnow;

                end

            end

            allRows = allRows(1:currentRow - 1, :);

            if isempty(allRows)

                app.BatchResultsTable = table();
                app.BatchSummaryTable = table();

                app.BatchResultsUITable.Data = {};
                return;

            end

            % Convert cell results into a numeric table
            app.BatchResultsTable = cell2table( ...
                allRows, ...
                'VariableNames', { ...
                'Image', ...
                'Method', ...
                'Precision', ...
                'Recall', ...
                'F1', ...
                'IoU', ...
                'TimeSeconds', ...
                'Status'});

            % Normalize text columns
            app.BatchResultsTable.Image = string(app.BatchResultsTable.Image);
            app.BatchResultsTable.Method = string(app.BatchResultsTable.Method);
            app.BatchResultsTable.Status = string(app.BatchResultsTable.Status);

            % Build dataset summary
            app.BatchSummaryTable = app.buildBatchSummary( app.BatchResultsTable);

            % Build IoU Distribution
            app.BatchIoUDistributionTable = app.buildBatchIoUDistribution( app.BatchResultsTable);

            % Update both UI tables
            app.updateBatchTables();

        end

        %% batch 显示辅助
        function setBatchComponentsVisible(app, state)

            % Image folder
            app.BatchImageFolderEditFieldLabel.Visible = state;
            app.BatchImageFolderEditField.Visible = state;
            app.BrowseBatchImagesButton.Visible = state;

            % Ground-truth folder
            app.GTFolderLabel.Visible = state;
            app.BatchGTFolderEditField.Visible = state;
            app.BrowseBatchGTButton.Visible = state;

            % Output folder
            app.OutputFolderLabel.Visible = state;
            app.BatchOutputFolderEditField.Visible = state;
            app.BrowseBatchOutputButton.Visible = state;

            % Batch controls
            app.BatchStatusLabel.Visible = state;
            app.RunBatchButton.Visible = state;

            % Batch result area
            app.BatchTitleLabel.Visible = state;
            app.BatchBestValueNoteLabel.Visible = state;
            app.BatchIoUDistributionLabel.Visible = state;
            app.BatchResultHighlightNoteLabel.Visible = state;

            app.BatchSummaryUITable.Visible = state;
            app.BatchResultsUITable.Visible = state;
            app.BatchIoUDistributionUITable.Visible = state;
            
            
        end

        %% Batch 隐藏 Original 和 Ground Truth
        function setSharedImageAxesVisible(app, state)

            axesList = [ app.OriginalUIAxes, app.GroundTruthUIAxes];

            for ax = axesList

                ax.Visible = state;
                ax.Title.Visible = state;

                if ~isempty(ax.Children)
                    set(ax.Children, 'Visible', state);
                end

            end

        end

        %% 
        function restoreBatchControls(app)

            if ~isvalid(app) || isempty(app.UIFigure) || ...
                    ~isvalid(app.UIFigure)

                return;
            end

            app.BatchIsRunning = false;

            app.DetectionModeButtonGroup.Enable = 'on';

            % Enable Run Batch only when all folders remain valid
            hasImageFolder = ...
                ~isempty(app.BatchImageFolder) && ...
                isfolder(app.BatchImageFolder);

            hasGTFolder = ...
                ~isempty(app.BatchGroundTruthFolder) && ...
                isfolder(app.BatchGroundTruthFolder);

            hasOutputFolder = ...
                ~isempty(app.BatchOutputFolder) && ...
                isfolder(app.BatchOutputFolder);

            if hasImageFolder && hasGTFolder && hasOutputFolder
                app.RunBatchButton.Enable = 'on';
            else
                app.RunBatchButton.Enable = 'off';
            end

        end

        %% 状态更新
        function updateBatchSetupStatus(app)

            hasImageFolder = ~isempty(app.BatchImageFolder) && ...
                isfolder(app.BatchImageFolder);

            hasGTFolder = ~isempty(app.BatchGroundTruthFolder) && ...
                isfolder(app.BatchGroundTruthFolder);

            hasOutputFolder = ~isempty(app.BatchOutputFolder) && ...
                isfolder(app.BatchOutputFolder);

            if ~hasImageFolder

                app.BatchStatusLabel.Text = 'Select image folder';
                app.RunBatchButton.Enable = 'off';

            elseif ~hasGTFolder

                app.BatchStatusLabel.Text = 'Select GT folder';
                app.RunBatchButton.Enable = 'off';

            elseif ~hasOutputFolder

                app.BatchStatusLabel.Text = 'Select output folder';
                app.RunBatchButton.Enable = 'off';

            else

                app.BatchStatusLabel.Text = 'Ready to run';
                app.RunBatchButton.Enable = 'on';

            end

        end

        %% count函数
        function distributionTable = buildBatchIoUDistribution(~, resultsTable)

            methodNames = ["Otsu"; "Sauvola"; "Canny"; "U-Net"];

            % 10 intervals: [0,0.1), ..., [0.9,1.0]
            binEdges = 0:0.1:1;

            methodColumn = strings(4, 1);
            binCounts = zeros(4, 10);

            for methodIndex = 1:4

                methodName = methodNames(methodIndex);
                methodColumn(methodIndex) = methodName;

                methodRows = resultsTable.Method == methodName;

                validIoU = resultsTable.IoU(methodRows);
                validIoU = validIoU(~isnan(validIoU));

                % Prevent tiny numerical values above 1
                validIoU = max(0, min(1, validIoU));

                binCounts(methodIndex, :) = histcounts( validIoU, binEdges);

            end

            distributionTable = array2table( ...
                binCounts, ...
                'VariableNames', { ...
                'IoU_0_01', ...
                'IoU_01_02', ...
                'IoU_02_03', ...
                'IoU_03_04', ...
                'IoU_04_05', ...
                'IoU_05_06', ...
                'IoU_06_07', ...
                'IoU_07_08', ...
                'IoU_08_09', ...
                'IoU_09_10'});

            distributionTable = addvars( distributionTable, ...
                methodColumn, 'Before', 1, ...
                'NewVariableNames', 'Method');

        end

        %% batch 保存函数
        function saveBatchResults(app, wasCancelled, savedFolder)

            if isempty(app.BatchResultsTable) || ...
                    ~istable(app.BatchResultsTable) || ...
                    height(app.BatchResultsTable) == 0

                error( 'BatchSave:NoResults', ...
                    'No batch results are available to save.');
            end

            if isempty(app.BatchOutputFolder) || ...
                    ~isfolder(app.BatchOutputFolder)

                error( 'BatchSave:InvalidOutputFolder', ...
                    'The batch output folder is invalid.');
            end

            if isempty(savedFolder) || ~isfolder(savedFolder)

                error( 'BatchSave:InvalidRunFolder', ...
                    'The batch run folder is invalid.');

            end

            [~, datasetName] = fileparts(app.BatchImageFolder);
            datasetName = regexprep(datasetName, '[^\w-]', '_');

            % Per-image numeric results
            perImagePath = fullfile( savedFolder, [datasetName, '_per_image_metrics.csv']);

            writetable( app.BatchResultsTable, perImagePath);

            % Summary numeric results
            if ~isempty(app.BatchSummaryTable) && ...
                    istable(app.BatchSummaryTable) && ...
                    height(app.BatchSummaryTable) > 0

                summaryPath = fullfile( savedFolder, [datasetName, '_summary_metrics.csv']);

                writetable( app.BatchSummaryTable, summaryPath);
            end

            % Save batch settings
            settingsPath = fullfile( savedFolder, [datasetName,'_batch_settings.txt']);

            % Save batch iou distribution
            if ~isempty(app.BatchIoUDistributionTable) && ...
                    istable(app.BatchIoUDistributionTable) && ...
                    height(app.BatchIoUDistributionTable) > 0

                distributionPath = fullfile( savedFolder, [datasetName, '_iou_distribution.csv']);

                writetable( app.BatchIoUDistributionTable, distributionPath);
            end

            fileID = fopen(settingsPath, 'w');

            if fileID == -1
                error( 'BatchSave:SettingsFileFailed', ...
                    'Unable to create the batch settings file.');
            end

            settingsCleanup = onCleanup(@() fclose(fileID));

            fprintf(fileID, 'Road Crack Detection Batch Processing\n');
            fprintf(fileID, 'Date: %s\n', datestr(now));
            fprintf(fileID, 'Image Folder: %s\n', app.BatchImageFolder);
            fprintf(fileID, 'Ground Truth Folder: %s\n', app.BatchGroundTruthFolder);
            fprintf(fileID, 'Output Folder: %s\n', app.BatchOutputFolder);

            fprintf(fileID, 'Post-processing Applied: %s\n', ...
            string(logical( app.ApplyPostprocessingCheckBox.Value)));
            fprintf(fileID, 'Cancelled: %s\n', string(logical(wasCancelled)));
            fprintf(fileID, 'Completed Result Rows: %d\n', height(app.BatchResultsTable));

            if ~isempty(app.BatchSummaryTable) && istable(app.BatchSummaryTable)

                fprintf(fileID, 'Methods Summarised: %d\n', height(app.BatchSummaryTable));
            end

        end

        %% Create output folder for the current batch run
        function savedFolder = createBatchRunFolder(app)

            if isempty(app.BatchOutputFolder) || ...
                    ~isfolder(app.BatchOutputFolder)

                error( 'BatchSave:InvalidOutputFolder', ...
                    'The batch output folder is invalid.');
            end

            timestamp = datestr(now, 'yyyymmdd_HHMMSS');

            [~, datasetName] = fileparts(app.BatchImageFolder);
            datasetName = regexprep(datasetName, '[^\w-]', '_');

            folderName = sprintf( 'Batch_%s_%s', datasetName, timestamp);

            savedFolder = fullfile( app.BatchOutputFolder, folderName);

            if ~isfolder(savedFolder)
                mkdir(savedFolder);
            end

            % Create mask folders
            maskRootFolder = fullfile(savedFolder, 'masks');

            methodFolders = { ...
                'Otsu', ...
                'Sauvola', ...
                'Canny', ...
                'UNet'};

            for methodIndex = 1:numel(methodFolders)

                currentFolder = fullfile( maskRootFolder, ...
                    methodFolders{methodIndex});

                if ~isfolder(currentFolder)
                    mkdir(currentFolder);
                end

            end

        end

        %% summary 最佳值标红
        function highlightBestBatchSummary(app)

            % Remove styles from previous batch results
            removeStyle(app.BatchSummaryUITable);

            if isempty(app.BatchSummaryTable) || ...
                    ~istable(app.BatchSummaryTable) || ...
                    height(app.BatchSummaryTable) == 0

                return;
            end

            summary = app.BatchSummaryTable;

            bestStyle = uistyle( 'FontColor', [0.85 0.10 0.10]);

            % Larger values are better:
            % Precision, Recall, F1 and IoU
            maximumMetricData = [ ...
                summary.MeanPrecision, ...
                summary.MeanRecall, ...
                summary.MeanF1, ...
                summary.MeanIoU];

            % Corresponding columns in BatchSummaryUITable
            maximumMetricColumns = 4:7;

            for metricIndex = 1:size(maximumMetricData, 2)

                metricValues = maximumMetricData(:, metricIndex);

                if all(isnan(metricValues))
                    continue;
                end

                bestValue = max(metricValues, [], 'omitnan');

                bestRows = find( ~isnan(metricValues) & ...
                    metricValues == bestValue);

                tableColumn = maximumMetricColumns(metricIndex);

                addStyle( ...
                    app.BatchSummaryUITable, ...
                    bestStyle, ...
                    'cell', ...
                    [bestRows, ...
                    repmat(tableColumn, numel(bestRows), 1)]);

            end

            % Smaller IoU standard deviation is better
            iouStdValues = summary.StdIoU;

            if ~all(isnan(iouStdValues))

                bestIoUStd = min( iouStdValues, [], 'omitnan');
                bestRows = find( ~isnan(iouStdValues) & iouStdValues == bestIoUStd);

                addStyle( app.BatchSummaryUITable, ...
                    bestStyle, 'cell', ...
                    [bestRows, repmat(8, numel(bestRows), 1)]);

            end

            % Smaller mean processing time is better
            timeValues = summary.MeanTimeSeconds;

            if ~all(isnan(timeValues))

                bestTime = min( timeValues, [], 'omitnan');
                bestRows = find( ~isnan(timeValues) & timeValues == bestTime);

                addStyle( app.BatchSummaryUITable, ...
                    bestStyle, 'cell', ...
                    [bestRows, repmat(9, numel(bestRows), 1)]);

            end

            % Fewer failures are better
            failureValues = summary.Failures;

            if ~isempty(failureValues)

                bestFailures = min(failureValues);
                bestRows = find( failureValues == bestFailures);

                addStyle( app.BatchSummaryUITable, ...
                    bestStyle, 'cell', ...
                    [bestRows, repmat(10, numel(bestRows), 1)]);

            end

        end

        %% pre-image 标红标蓝
        function highlightBatchResultValues(app)

            % Remove styles from previous results
            removeStyle(app.BatchResultsUITable);

            if isempty(app.BatchResultsTable) || ...
                    ~istable(app.BatchResultsTable) || ...
                    height(app.BatchResultsTable) == 0

                return;
            end

            results = app.BatchResultsTable;

            bestStyle = uistyle( 'FontColor', [0.85 0.10 0.10]);
            worstStyle = uistyle( 'FontColor', [0.10 0.35 0.85]);

            % Data fields and corresponding UI table columns
            metricNames = { ...
                'Precision', ...
                'Recall', ...
                'F1', ...
                'IoU', ...
                'TimeSeconds'};

            uiColumns = 3:7;

            for metricIndex = 1:numel(metricNames)

                values = results.(metricNames{metricIndex});
                tableColumn = uiColumns(metricIndex);

                % Ignore NaN values
                validRows = find(~isnan(values));

                if isempty(validRows)
                    continue;
                end

                validValues = values(validRows);

                % Do not highlight if every valid value is identical
                if max(validValues) == min(validValues)
                    continue;
                end

                if strcmp(metricNames{metricIndex}, 'TimeSeconds')

                    % Processing time: smaller is better
                    bestValue = min(validValues);
                    worstValue = max(validValues);

                else

                    % Evaluation metrics: larger is better
                    bestValue = max(validValues);
                    worstValue = min(validValues);

                end

                % All tied best values
                bestRows = validRows(validValues == bestValue);

                % All tied worst values
                worstRows = validRows(validValues == worstValue);

                addStyle( ...
                    app.BatchResultsUITable, ...
                    bestStyle, ...
                    'cell', ...
                    [bestRows, ...
                    repmat(tableColumn, numel(bestRows), 1)]);

                addStyle( ...
                    app.BatchResultsUITable, ...
                    worstStyle, ...
                    'cell', ...
                    [worstRows, ...
                    repmat(tableColumn, numel(worstRows), 1)]);

            end

        end

        %% Double-clicked function: 选择具体案例
        function openSelectedBatchResult(app, event)

            % Ignore interaction while Batch is running
            if app.BatchIsRunning
                return;
            end

            % Row in the original table data
            selectedRow = event.InteractionInformation.Row;

            % User may have double-clicked a header or empty area
            if isempty(selectedRow)
                return;
            end

            % Validate stored Batch results
            if isempty(app.BatchResultsTable) || ...
                    ~istable(app.BatchResultsTable) || ...
                    height(app.BatchResultsTable) == 0

                uialert( app.UIFigure, ...
                    'No Batch results are available.', ...
                    'Batch Results Unavailable');

                return;
            end

            if selectedRow > height(app.BatchResultsTable)
                return;
            end

            % Validate the output folder from the latest Batch run
            if isempty(app.BatchLastRunFolder) || ...
                    ~isfolder(app.BatchLastRunFolder)

                uialert( app.UIFigure, ...
                    ['The saved folder for the latest Batch run ', ...
                    'is unavailable. Please run Batch processing again.'], ...
                    'Batch Folder Unavailable');

                return;
            end

            try

                % 1. Determine which image was double-clicked

                selectedImageName = app.BatchResultsTable.Image(selectedRow);

                selectedImageName = string(selectedImageName);

                imagePath = fullfile( app.BatchImageFolder, ...
                    char(selectedImageName));

                if ~isfile(imagePath)

                    error( 'BatchViewer:ImageNotFound', ...
                        'Original image not found:%s%s', ...
                        newline, imagePath);

                end

                % Read and preprocess the original image
                inputImage = imread(imagePath);

                [rgbImage, grayImage] = preprocessImage( ...
                    inputImage, app.Config);

                targetSize = size(grayImage);

                % 2. Load Ground Truth

                gtPath = app.findBatchGroundTruth(imagePath);

                if ~isempty(gtPath) && isfile(gtPath)

                    gtRaw = imread(gtPath);

                    if ndims(gtRaw) == 3
                        gtRaw = rgb2gray(gtRaw);
                    end

                    gtMask = gtRaw > 0;

                    if ~isequal(size(gtMask), targetSize)

                        gtMask = imresize( gtMask, ...
                            targetSize, 'nearest');

                    end

                    gtMask = logical(gtMask);

                else

                    gtPath = '';
                    gtMask = [];

                end

                % 3. Load the four saved Batch masks

                methodNames = [ ...
                    "Otsu", ...
                    "Sauvola", ...
                    "Canny", ...
                    "U-Net"];

                % Folder and filename use UNet rather than U-Net
                savedMethodNames = { ...
                    'Otsu', ...
                    'Sauvola', ...
                    'Canny', ...
                    'UNet'};

                compareMasks = cell(1, 4);
                compareTimes = nan(1, 4);

                [~, baseName, ~] = fileparts(imagePath);

                for methodIndex = 1:4

                    savedMethodName = savedMethodNames{methodIndex};

                    maskFileName = sprintf( '%s_%s_mask.png', ...
                        baseName, savedMethodName);

                    maskPath = fullfile( app.BatchLastRunFolder, ...
                        'masks', savedMethodName, maskFileName);

                    if ~isfile(maskPath)

                        error( 'BatchViewer:MaskNotFound', ...
                            ['Saved %s mask not found:%s%s%s', ...
                            'The Batch run may have failed or been cancelled.'], ...
                            methodNames(methodIndex), ...
                            newline, maskPath, newline);

                    end

                    maskRaw = imread(maskPath);

                    if ndims(maskRaw) == 3
                        maskRaw = maskRaw(:, :, 1);
                    end

                    currentMask = maskRaw > 0;

                    if ~isequal(size(currentMask), targetSize)

                        currentMask = imresize( currentMask, ...
                            targetSize, 'nearest');

                    end

                    compareMasks{methodIndex} = logical(currentMask);

                    % Find processing time stored for this image and method
                    resultRow = find( ...
                        app.BatchResultsTable.Image == ...
                        selectedImageName & ...
                        app.BatchResultsTable.Method == ...
                        methodNames(methodIndex), ...
                        1, 'first');

                    if ~isempty(resultRow)

                        compareTimes(methodIndex) = ...
                            app.BatchResultsTable.TimeSeconds(resultRow);

                    end

                end

                % 4. Store the selected image as the current App image

                app.CurrentImage = inputImage;
                app.CurrentImagePath = imagePath;

                app.GroundTruthMask = gtMask;
                app.GroundTruthPath = gtPath;

                app.CompareMasks = compareMasks;
                app.CompareTimes = compareTimes;
                app.CompareRGBImage = rgbImage;
                app.CompareMetricsTable = table();

                app.CompareResultsValid = true;

                % Match the checkbox to the setting used by the Batch run
                app.ApplyPostprocessingCheckBox.Value = ...
                    app.BatchPostprocessingApplied;

                % 5. Switch from Batch to Compare mode

                app.CompareButton.Value = true;

                % Programmatically changing Value does not execute the
                % SelectionChanged callback, so invoke the layout update
                app.DetectionModeButtonGroupSelectionChanged([]);

                % 6. Display original image and Ground Truth

                imshow( inputImage, 'Parent', app.OriginalUIAxes);

                title( app.OriginalUIAxes, 'Original Image');

                axis(app.OriginalUIAxes, 'image');
                axis(app.OriginalUIAxes, 'off');

                if ~isempty(gtMask)

                    imshow( gtMask, 'Parent', app.GroundTruthUIAxes);

                    title( app.GroundTruthUIAxes, 'Ground Truth');

                else

                    cla(app.GroundTruthUIAxes);

                    title( app.GroundTruthUIAxes, 'Ground Truth Not Found');

                end

                axis(app.GroundTruthUIAxes, 'image');
                axis(app.GroundTruthUIAxes, 'off');

                % 7. Show the four methods as overlays

                app.CompareViewDropDown.Value = 'Overlay';

                app.updateComparisonDisplay();
                app.updateComparisonTable();

                app.SaveResultsButton.Enable = 'on';

                app.StatusLamp.Color = [0.20 0.65 0.25];

                app.StatusLabel.Text = sprintf( ...
                    'Loaded Batch result: %s', ...
                    char(selectedImageName));

                app.scheduleStatusReset();

            catch ME

                app.StatusLamp.Color = [0.85 0.20 0.20];
                app.StatusLabel.Text = 'Unable to open Batch result';

                uialert( app.UIFigure, ...
                    getReport(ME, 'basic', 'hyperlinks', 'off'), ...
                    'Batch Result Viewer Error');

            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                % Locate project root from the App location

                appFilePath = mfilename('fullpath');
                appFolder = fileparts(appFilePath);

                % Expected structure:
                % projectRoot/src/m6_app/AppFile.m
                srcFolder = fileparts(appFolder);
                projectRoot = fileparts(srcFolder);

                % Add configuration folder

                cfgFolder = fullfile(projectRoot, 'cfg');
                configFile = fullfile(cfgFolder, 'defaultConfig.m');

                if ~isfolder(cfgFolder)
                    error( 'AppStartup:ConfigFolderNotFound', ...
                        ['Configuration folder not found:\n%s\n\n', ...
                        'Please keep the project folder structure unchanged.'], ...
                        cfgFolder);
                end

                if ~isfile(configFile)
                    error( 'AppStartup:ConfigFileNotFound', ...
                        'defaultConfig.m not found:\n%s', ...
                        configFile);
                end

                addpath(cfgFolder, '-begin');

                % Load configuration

                app.Config = defaultConfig();

                % Add project modules

                moduleFolders = { ...
                    fullfile(projectRoot, 'src', 'm0_data'), ...
                    fullfile(projectRoot, 'src', 'm1_pre'), ...
                    fullfile(projectRoot, 'src', 'm2_cls'), ...
                    fullfile(projectRoot, 'src', 'm3_post'), ...
                    fullfile(projectRoot, 'src', 'm4_dl'), ...
                    fullfile(projectRoot, 'src', 'm5_eval'), ...
                    fullfile(projectRoot, 'src', 'm6_app')};

                for folderIndex = 1:numel(moduleFolders)

                    if ~isfolder(moduleFolders{folderIndex})
                        error( 'AppStartup:ModuleFolderNotFound', ...
                            'Required module folder not found:\n%s', ...
                            moduleFolders{folderIndex});
                    end

                    addpath(moduleFolders{folderIndex}, '-begin');
                end

                % Validate model path

                if ~isfile(app.Config.unetModelPath)
                    warning( 'AppStartup:ModelNotFound', ...
                        'U-Net model not found:\n%s', ...
                        app.Config.unetModelPath);
                end

                % Ready status

                app.StatusLamp.Color = [0.65 0.65 0.65];
                app.StatusLabel.Text = 'Ready';
                app.SaveResultsButton.Enable = 'off';

                % Compare-mode axes are hidden by default
                app.OtsuUIAxes.Visible = 'off';
                app.SauvolaUIAxes.Visible = 'off';
                app.CannyUIAxes.Visible = 'off';
                app.UNetUIAxes.Visible = 'off';
                app.setBatchComponentsVisible('off');

                app.resetComparisonResults();
                app.ComparisonUITable.Visible = 'off';

                app.BatchIoUDistributionTable = table();
                app.BatchIoUDistributionUITable.Data = { ...
                    'Otsu',    '—', '—', '—', '—', '—', '—', '—', '—', '—'; ...
                    'Sauvola', '—', '—', '—', '—', '—', '—', '—', '—', '—'; ...
                    'Canny',   '—', '—', '—', '—', '—', '—', '—', '—', '—'; ...
                    'U-Net',   '—', '—', '—', '—', '—', '—', '—', '—', '—'};

                app.BatchSummaryUITable.Data = { ...
                    'Otsu',    '—', '—', '—', '—', '—', '—', '—', '—', '—'; ...
                    'Sauvola', '—', '—', '—', '—', '—', '—', '—', '—', '—'; ...
                    'Canny',   '—', '—', '—', '—', '—', '—', '—', '—', '—'; ...
                    'U-Net',   '—', '—', '—', '—', '—', '—', '—', '—', '—'};

                app.BatchResultsUITable.Data = {};

                app.BatchImageFolder = '';
                app.BatchGroundTruthFolder = '';
                app.BatchOutputFolder = '';

                app.BatchImageFolderEditField.Value = '';
                app.BatchGTFolderEditField.Value = '';
                app.BatchOutputFolderEditField.Value = '';

                app.BatchStatusLabel.Text = 'No batch selected';
                app.RunBatchButton.Enable = 'off';

            catch ME

                app.StatusLamp.Color = [0.85 0.20 0.20];
                app.StatusLabel.Text = 'Startup failed';

                uialert( app.UIFigure, ME.message, 'App Startup Error');
            end


        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)

            app.StatusLamp.Color = [1.00 0.65 0.00];
            app.StatusLabel.Text = 'Loading...';

            [file, path] = uigetfile( {'*.jpg; *.jpeg', ...
                'Road Images (*.jpg, *.jpeg)'}, 'Select a road image');

            if isequal(file, 0)
                
                app.StatusLamp.Color = [0.20 0.25 0.85];
                app.StatusLabel.Text = 'Load cancelled';

                app.scheduleStatusReset();
                
                return;
            end

            try
                imagePath = fullfile(path, file);
                img = imread(imagePath);

                app.CurrentImage = img;
                app.CurrentImagePath = imagePath;

                % Display original image
                imshow(img, 'Parent', app.OriginalUIAxes);
                title(app.OriginalUIAxes, 'Original Image');
                axis(app.OriginalUIAxes, 'image');
                axis(app.OriginalUIAxes, 'off');

                % Automatically locate same-name PNG ground truth
                [~, baseName, ~] = fileparts(file);

                gtCandidates = { fullfile(path, [baseName, '.png']), ...
                    fullfile(path, [baseName, '_mask.png'])};

                gtPath = '';

                for candidateIndex = 1:numel(gtCandidates)

                    if isfile(gtCandidates{candidateIndex})

                        gtPath = gtCandidates{candidateIndex};

                        break;

                    end
                end

                if ~isempty(gtPath)

                    gtRaw = imread(gtPath);

                    if size(gtRaw, 3) == 3
                        gtRaw = rgb2gray(gtRaw);
                    end

                    % Convert 0/1 or 0/255 mask to logical
                    gtMask = gtRaw > 0;

                    % Match original-image size if necessary
                    imageSize = size(img, [1 2]);

                    if ~isequal(size(gtMask), imageSize)
                        gtMask = imresize( gtMask, imageSize, 'nearest');
                    end

                    app.GroundTruthMask = logical(gtMask);
                    app.GroundTruthPath = gtPath;

                    imshow(app.GroundTruthMask, 'Parent', app.GroundTruthUIAxes);
                    title(app.GroundTruthUIAxes, 'Ground Truth');
                    axis(app.GroundTruthUIAxes, 'image');
                    axis(app.GroundTruthUIAxes, 'off');

                else

                    app.GroundTruthMask = [];
                    app.GroundTruthPath = '';

                    cla(app.GroundTruthUIAxes);
                    title(app.GroundTruthUIAxes, 'Ground Truth Not Found');

                end

                app.GroundTruthUIAxes.XTick = [];
                app.GroundTruthUIAxes.YTick = [];
                app.GroundTruthUIAxes.XLabel.String = '';
                app.GroundTruthUIAxes.YLabel.String = '';

                % Clear previous detection results
                app.resetSingleResults();
                app.resetComparisonResults();

                % Disable saving until detection is run again
                app.SaveResultsButton.Enable = 'off';
                
                % Update status
                app.StatusLamp.Color = [0.20 0.65 0.25];
                app.StatusLabel.Text = 'Image loaded';

                app.scheduleStatusReset();

            catch ME
                app.StatusLamp.Color = [0.85 0.20 0.20];
                app.StatusLabel.Text = 'Loading failed';
                
                uialert(app.UIFigure, ME.message, 'Image Loading Error'); 
            end
        end

        % Button pushed function: RunDetectionButton
        function RunDetectionButtonPushed(app, event)

            % Check input image

            if isempty(app.CurrentImage)

                uialert( app.UIFigure, 'Please load a road image first.', 'No Image');

                return;
            end

            try
                % Start timer and update interface

                startTime = tic;

                app.StatusLamp.Color = [1.00 0.65 0.00];
                app.StatusLabel.Text = 'Detecting...';

                app.RunDetectionButton.Enable = 'off';
                app.LoadImageButton.Enable = 'off';
                app.SaveResultsButton.Enable = 'off';

                app.DetectionModeButtonGroup.Enable = 'off';
                app.DetectionMethodDropDown.Enable = 'off';
                app.ApplyPostprocessingCheckBox.Enable = 'off';
                app.CompareViewDropDown.Enable = 'off';

                % Invalidate the selected mode before a new run
                if app.SingleButton.Value
                    app.SingleResultsValid = false;
                else
                    app.CompareResultsValid = false;
                end

                drawnow;

                % Common preprocessing

                [rgbImage, grayImage] = preprocessImage( app.CurrentImage, app.Config);

                % Single mode
                if app.SingleButton.Value

                    % Single mode
                    app.runSingleDetection(rgbImage, grayImage);
                    methodName = app.DetectionMethodDropDown.Value;
                    app.StatusLabel.Text = sprintf( 'Complete - %s', methodName);

                else

                    % Compare mode
                    app.runComparison(rgbImage, grayImage);
                    app.StatusLabel.Text = 'Comparison complete';

                end

                % Common total processing time
                processingTime = toc(startTime);
                app.TimeEditField.Value = processingTime;

                % Common successful completion
                app.StatusLamp.Color = [0.20 0.65 0.25];

                app.RunDetectionButton.Enable = 'on';
                app.LoadImageButton.Enable = 'on';
                app.SaveResultsButton.Enable = 'on';

                app.DetectionModeButtonGroup.Enable = 'on';
                app.DetectionMethodDropDown.Enable = 'on';
                app.ApplyPostprocessingCheckBox.Enable = 'on';
                app.CompareViewDropDown.Enable = 'on';

                app.scheduleStatusReset();

            catch ME

                app.StatusLamp.Color = [0.85 0.20 0.20];
                app.StatusLabel.Text = 'Detection failed';

                app.RunDetectionButton.Enable = 'on';
                app.LoadImageButton.Enable = 'on';
                app.SaveResultsButton.Enable = 'off';

                app.DetectionModeButtonGroup.Enable = 'on';
                app.DetectionMethodDropDown.Enable = 'on';
                app.ApplyPostprocessingCheckBox.Enable = 'on';
                app.CompareViewDropDown.Enable = 'on';

                uialert( app.UIFigure, ME.message, 'Detection Error');
            end

        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            
            % Check whether detection has been completed

            if isempty(app.CurrentImage)

                uialert( app.UIFigure, ...
                    'Please run crack detection before saving results.', ...
                    'No Detection Result');

                return;
            end

            %Determine by pattern
            if app.SingleButton.Value

                hasResults = ...
                    app.SingleResultsValid && ...
                    ~isempty(app.RawMask) && ...
                    ~isempty(app.FinalMask);

            else

                hasResults = ...
                    app.CompareResultsValid && ...
                    iscell(app.CompareMasks) && ...
                    numel(app.CompareMasks) == 4 && ...
                    all(~cellfun(@isempty, app.CompareMasks));

            end

            if ~hasResults

                uialert( app.UIFigure, ...
                    ['The current results are unavailable or outdated. ', ...
                    'Please run detection again before saving.'], ...
                    'Invalid Detection Result');

                return;
            end

            % Update interface

            app.StatusLamp.Color = [1.00 0.65 0.00];
            app.StatusLabel.Text = 'Saving...';

            app.SaveResultsButton.Enable = 'off';
            app.RunDetectionButton.Enable = 'off';
            app.LoadImageButton.Enable = 'off';

            drawnow;

            % Determine initial output folder

            initialFolder = pwd;

            if ~isempty(app.Config) && ...
                    isfield(app.Config, 'resultRoot') && ...
                    isfolder(app.Config.resultRoot)

                initialFolder = app.Config.resultRoot;
            end

            % Let the user choose an output folder

            outputFolder = uigetdir( initialFolder, ...
                'Select a folder to save detection results');

            if isequal(outputFolder, 0)

                app.StatusLamp.Color = [0.20 0.25 0.85];
                app.StatusLabel.Text = 'Save cancelled';

                app.SaveResultsButton.Enable = 'on';
                app.RunDetectionButton.Enable = 'on';
                app.LoadImageButton.Enable = 'on';

                app.scheduleStatusReset();
                return;
            end

            try
                % Build file prefix

                [~, baseName, ~] = fileparts(app.CurrentImagePath);

                if app.SingleButton.Value

                    app.saveSingleResults(outputFolder, baseName);

                else

                    app.saveComparisonResults(outputFolder, baseName);

                end

                % Successful completion

                app.StatusLamp.Color = [0.20 0.65 0.25];
                app.StatusLabel.Text = 'Results saved';

                app.SaveResultsButton.Enable = 'on';
                app.RunDetectionButton.Enable = 'on';
                app.LoadImageButton.Enable = 'on';

                uialert( ...
                    app.UIFigure, ...
                    sprintf( ...
                    'Results saved successfully to:\n%s', ...
                    outputFolder), ...
                    'Save Complete', ...
                    'Icon', ...
                    'success');

                app.scheduleStatusReset();

            catch ME
                % Restore interface after error

                app.StatusLamp.Color = [0.85 0.20 0.20];
                app.StatusLabel.Text = 'Saving failed';

                app.SaveResultsButton.Enable = 'on';
                app.RunDetectionButton.Enable = 'on';
                app.LoadImageButton.Enable = 'on';

                uialert( app.UIFigure, ME.message, 'Saving Error');
            end

        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            % Clear stored data
            app.CurrentImage = [];
            app.CurrentImagePath = '';

            app.GroundTruthMask = [];
            app.GroundTruthPath = '';

            % Clear original image and ground truth
            cla(app.OriginalUIAxes);
            cla(app.GroundTruthUIAxes);

            title(app.OriginalUIAxes, 'Original Image');
            title(app.GroundTruthUIAxes, 'Ground Truth');

            sharedAxes = [ ...
                app.OriginalUIAxes, ...
                app.GroundTruthUIAxes];

            for ax = sharedAxes
                ax.XTick = [];
                ax.YTick = [];
                ax.XLabel.String = '';
                ax.YLabel.String = '';
            end

            % Clear all detection results
            app.resetSingleResults();
            app.resetComparisonResults();

            % Reset controls
            app.ApplyPostprocessingCheckBox.Value = false;
            app.DetectionMethodDropDown.Value = 'Otsu';

            % Reset status
            app.StatusLamp.Color = [0.65 0.65 0.65];
            app.StatusLabel.Text = 'Ready';

            % Disable saving until a new detection is completed
            app.SaveResultsButton.Enable = 'off';

        end

        % Selection changed function: DetectionModeButtonGroup
        function DetectionModeButtonGroupSelectionChanged(app, event)

            % Read selected mode
            selectedMode = app.DetectionModeButtonGroup.SelectedObject.Text;

            switch selectedMode

                case 'Single'

                    % Show single-mode controls
                    app.DetectionMethodDropDown.Visible = 'on';
                    app.DetectionMethodDropDownLabel.Visible = 'on';

                    % Restore original axes sizes and Postprocessing CheckBox
                    app.OriginalUIAxes.Position = [300 365 290 300];
                    app.GroundTruthUIAxes.Position = [590 365 290 300];
                    app.ApplyPostprocessingCheckBox.Position = [69 329 141 22];

                    % Status lamp + Ready
                    app.StatusLamp.Position  = [91,277,20,20];
                    app.StatusLabel.Position = [126,275,143,23];
                    
                    % Show Single results and hide Compare results and batch
                    app.setSingleResultsVisible('on');
                    app.setComparisonResultsVisible('off');

                    app.CompareViewDropDown.Visible = 'off';
                    app.ResultViewDropDownLabel.Visible = 'off';
                    app.BestValueNoteLabel.Visible = 'off';
                    app.ErrorMapLegendLabel.Visible = 'off';

                    app.EvaluationPanel.Visible = 'on';

                    % batch
                    app.setBatchComponentsVisible('off');
                    app.setSharedImageAxesVisible('on');

                    app.LoadImageButton.Visible = 'on';
                    app.ApplyPostprocessingCheckBox.Visible = 'on';
                    app.RunDetectionButton.Visible = 'on';
                    app.SaveResultsButton.Visible = 'on';
                    app.ClearButton.Visible = 'on';

                    app.StatusLamp.Color = [0.65 0.05 0.85];
                    app.StatusLabel.Text = 'Single mode';
                    
                case 'Compare'

                    % Hide single-mode controls
                    app.DetectionMethodDropDown.Visible = 'off';
                    app.DetectionMethodDropDownLabel.Visible = 'off';

                    app.setSingleResultsVisible('off');
                    app.EvaluationPanel.Visible = 'off';

                    % Resize shared axes for comparison layout
                    app.OriginalUIAxes.Position = [300 430 270 230];
                    app.GroundTruthUIAxes.Position = [590 430 270 230];

                    % Status lamp + Ready
                    app.StatusLamp.Position  = [91,277,20,20];
                    app.StatusLabel.Position = [126,275,143,23];

                    app.OtsuUIAxes.Position = [300 165 270 230];
                    app.SauvolaUIAxes.Position = [590 165 270 230];
                    app.CannyUIAxes.Position = [880 165 270 230];
                    app.UNetUIAxes.Position = [880 430 270 230];

                    app.ApplyPostprocessingCheckBox.Position = [69 329 141 22];

                    % Show comparison axes
                    app.setComparisonResultsVisible('on');

                    app.CompareViewDropDown.Visible = 'on';
                    app.ResultViewDropDownLabel.Visible = 'on';
                    app.BestValueNoteLabel.Visible = 'on';
                    app.ErrorMapLegendLabel.Visible = ...
                        strcmp(app.CompareViewDropDown.Value, 'Error Map');

                    % batch
                    app.setBatchComponentsVisible('off');
                    app.setSharedImageAxesVisible('on');

                    app.LoadImageButton.Visible = 'on';
                    app.ApplyPostprocessingCheckBox.Visible = 'on';
                    app.RunDetectionButton.Visible = 'on';
                    app.SaveResultsButton.Visible = 'on';
                    app.ClearButton.Visible = 'on';

                    app.StatusLamp.Color = [0.65 0.05 0.85];
                    app.StatusLabel.Text = 'Comparison mode';

                case 'Batch'

                    % Hide Single and Compare results
                    app.setSingleResultsVisible('off');
                    app.setComparisonResultsVisible('off');
                    app.setSharedImageAxesVisible('off');

                    % Hide Single/Compare controls
                    app.DetectionMethodDropDown.Visible = 'off';
                    app.DetectionMethodDropDownLabel.Visible = 'off';

                    app.CompareViewDropDown.Visible = 'off';
                    app.ResultViewDropDownLabel.Visible = 'off';

                    app.LoadImageButton.Visible = 'off';
                    app.RunDetectionButton.Visible = 'off';
                    app.SaveResultsButton.Visible = 'off';
                    app.ClearButton.Visible = 'off';

                    app.EvaluationPanel.Visible = 'off';
                    app.BestValueNoteLabel.Visible = 'off';
                    app.ErrorMapLegendLabel.Visible = 'off';

                    % Show Batch interface
                    app.setBatchComponentsVisible('on');

                    app.SaveResultsButton.Enable = 'off';

                    app.ApplyPostprocessingCheckBox.Position = [70 270 170 22];

                    % Status lamp + Ready
                    app.StatusLamp.Position  = [92 230 20 20];
                    app.StatusLabel.Position = [122 227 120 23];

                    % Batch setup status
                    app.BatchStatusLabel.Position = [25 190 220 22];

                    % Run Batch
                    app.RunBatchButton.Position = [45 105 180 55];

                    app.StatusLamp.Color = [0.65 0.05 0.85];
                    app.StatusLabel.Text = 'Batch mode';

            end

            switch selectedMode

                case 'Single'

                    hasResults = ...
                        app.SingleResultsValid && ...
                        ~isempty(app.RawMask) && ...
                        ~isempty(app.FinalMask);

                    if hasResults
                        app.SaveResultsButton.Enable = 'on';
                    else
                        app.SaveResultsButton.Enable = 'off';
                    end

                case 'Compare'

                    hasResults = ...
                        app.CompareResultsValid && ...
                        iscell(app.CompareMasks) && ...
                        numel(app.CompareMasks) == 4 && ...
                        all(~cellfun(@isempty, app.CompareMasks));

                    if hasResults
                        app.SaveResultsButton.Enable = 'on';
                    else
                        app.SaveResultsButton.Enable = 'off';
                    end

                case 'Batch'

                    app.SaveResultsButton.Enable = 'off';

            end

            app.scheduleStatusReset();

        end

        % Value changed function: DetectionMethodDropDown
        function DetectionMethodDropDownValueChanged(app, event)

            if app.SingleButton.Value && ...
                    app.SingleResultsValid && ...
                    ~isempty(app.RawMask) && ...
                    ~isempty(app.FinalMask)

                app.markCurrentResultsOutdated();

            end
            
        end

        % Value changed function: ApplyPostprocessingCheckBox
        function ApplyPostprocessingCheckBoxValueChanged(app, event)
            
            hasSingleResults = ...
                app.SingleResultsValid && ...
                ~isempty(app.RawMask) && ...
                ~isempty(app.FinalMask);

            hasCompareResults = ...
                app.CompareResultsValid && ...
                iscell(app.CompareMasks) && ...
                numel(app.CompareMasks) == 4 && ...
                all(~cellfun(@isempty, app.CompareMasks));

            if hasSingleResults || hasCompareResults

                app.SingleResultsValid = false;
                app.CompareResultsValid = false;

                app.SaveResultsButton.Enable = 'off';

                app.StatusLamp.Color = [1.00 0.65 0.00];
                app.StatusLabel.Text = 'Rerun required';

                app.scheduleStatusReset();

            end
            
        end

        % Value changed function: CompareViewDropDown
        function CompareViewDropDownValueChanged(app, event)
            if ~app.CompareButton.Value || ...
                    ~iscell(app.CompareMasks) || ...
                    numel(app.CompareMasks) ~= 4 || ...
                    any(cellfun(@isempty, app.CompareMasks))

                return;
            end

            % Error Map requires ground truth
            if strcmp(app.CompareViewDropDown.Value, 'Error Map') && ...
                    isempty(app.GroundTruthMask)

                app.CompareViewDropDown.Value = 'Mask';
                app.ErrorMapLegendLabel.Visible = 'off';

                uialert( app.UIFigure, 'Error Map requires a ground truth mask.', ...
                    'Ground Truth Unavailable');

                app.updateComparisonDisplay();
                return;
            end

            % Show legend only in Error Map view
            app.ErrorMapLegendLabel.Visible = ...
                strcmp(app.CompareViewDropDown.Value, 'Error Map');

                app.updateComparisonDisplay();
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            app.cleanup();
            
            delete(app)

        end

        % Button pushed function: BrowseBatchImagesButton
        function BrowseBatchImagesButtonPushed(app, event)
            initialFolder = pwd;

            if ~isempty(app.BatchImageFolder) && isfolder(app.BatchImageFolder)

                initialFolder = app.BatchImageFolder;
            end

            selectedFolder = uigetdir( initialFolder, 'Select batch image folder');

            if isequal(selectedFolder, 0)
                return;
            end

            app.BatchImageFolder = selectedFolder;
            app.BatchImageFolderEditField.Value = selectedFolder;

            app.updateBatchSetupStatus();

        end

        % Button pushed function: BrowseBatchGTButton
        function BrowseBatchGTButtonPushed(app, event)
            initialFolder = pwd;

            if ~isempty(app.BatchGroundTruthFolder) && isfolder(app.BatchGroundTruthFolder)

                initialFolder = app.BatchGroundTruthFolder;
            end

            selectedFolder = uigetdir( initialFolder, 'Select ground truth folder');

            if isequal(selectedFolder, 0)
                return;
            end

            app.BatchGroundTruthFolder = selectedFolder;
            app.BatchGTFolderEditField.Value = selectedFolder;

            app.updateBatchSetupStatus();
        end

        % Button pushed function: BrowseBatchOutputButton
        function BrowseBatchOutputButtonPushed(app, event)
            initialFolder = pwd;

            if ~isempty(app.BatchOutputFolder) && ...
                    isfolder(app.BatchOutputFolder)

                initialFolder = app.BatchOutputFolder;

            elseif ~isempty(app.Config) && ...
                    isfield(app.Config, 'resultRoot') && ...
                    isfolder(app.Config.resultRoot)

                initialFolder = app.Config.resultRoot;
            end

            selectedFolder = uigetdir( ...
                initialFolder, ...
                'Select batch output folder');

            if isequal(selectedFolder, 0)
                return;
            end

            app.BatchOutputFolder = selectedFolder;
            app.BatchOutputFolderEditField.Value = selectedFolder;

            app.updateBatchSetupStatus();
        end

        % Button pushed function: RunBatchButton
        function RunBatchButtonPushed(app, event)
            % Prevent repeated execution
            if app.BatchIsRunning
                return;
            end

            app.BatchIsRunning = true;

            % Disable controls during batch processing
            app.RunBatchButton.Enable = 'off';
            app.DetectionModeButtonGroup.Enable = 'off';

            % Always restore controls when this callback finishes
            restoreControls = onCleanup( @() app.restoreBatchControls());

            try

                app.StatusLamp.Color = [1.00 0.65 0.00];
                app.StatusLabel.Text = 'Batch running';
                app.BatchStatusLabel.Text = 'Processing...';

                drawnow;

                % Create the output folder before processing starts
                savedFolder = app.createBatchRunFolder();

                % Remember this run so that table rows can reopen its saved masks
                app.BatchLastRunFolder = savedFolder;

                app.BatchPostprocessingApplied = logical(app.ApplyPostprocessingCheckBox.Value);

                % Run processing and save masks progressively
                wasCancelled = app.runBatchProcessing(savedFolder);

                if wasCancelled

                    app.StatusLamp.Color = [0.20 0.40 0.90];
                    app.StatusLabel.Text = 'Batch cancelled';
                    app.BatchStatusLabel.Text = 'Processing cancelled';

                else

                    app.StatusLamp.Color = [0.20 0.65 0.25];
                    app.StatusLabel.Text = 'Batch complete';
                    app.BatchStatusLabel.Text = 'Processing complete';

                end

                % Automatically save completed batch results
                if ~isempty(app.BatchResultsTable) && ...
                        istable(app.BatchResultsTable) && ...
                        height(app.BatchResultsTable) > 0

                    app.saveBatchResults( wasCancelled, savedFolder);

                    app.BatchStatusLabel.Text = sprintf( '%d rows saved', ...
                        height(app.BatchResultsTable));

                    uialert( ...
                        app.UIFigure, ...
                        sprintf( ...
                        'Batch results saved successfully to:\n%s', ...
                        savedFolder), ...
                        'Batch Save Complete', ...
                        'Icon', ...
                        'success');

                end

            catch ME

                app.StatusLamp.Color = [0.85 0.20 0.20];
                app.StatusLabel.Text = 'Batch failed';
                app.BatchStatusLabel.Text = 'Processing failed';

                uialert( app.UIFigure, ME.message, 'Batch Processing Error');

            end

            app.scheduleStatusReset();

        end

        % Double-clicked callback: BatchResultsUITable
        function BatchResultsUITableDoubleClicked(app, event)
            
            app.openSelectedBatchResult(event);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Road Crack Detection System';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RawMaskUIAxes = uiaxes(app.UIFigure);
            title(app.RawMaskUIAxes, 'Raw Result')
            app.RawMaskUIAxes.XTick = [];
            app.RawMaskUIAxes.YTick = [];
            app.RawMaskUIAxes.Toolbar = [];
            app.OriginalUIAxes = uiaxes(app.UIFigure);
            title(app.OriginalUIAxes, 'Original Image')
            app.OriginalUIAxes.XTick = [];
            app.OriginalUIAxes.YTick = [];
            app.OriginalUIAxes.Toolbar = [];
            app.FinalMaskUIAxes = uiaxes(app.UIFigure);
            title(app.FinalMaskUIAxes, 'Post-processed Result')
            app.FinalMaskUIAxes.XTick = [];
            app.FinalMaskUIAxes.YTick = [];
            app.FinalMaskUIAxes.Toolbar = [];
            app.GroundTruthUIAxes = uiaxes(app.UIFigure);
            title(app.GroundTruthUIAxes, 'Ground Truth')
            app.GroundTruthUIAxes.XTick = [];
            app.GroundTruthUIAxes.YTick = [];
            app.GroundTruthUIAxes.Toolbar = [];
            app.OverlayUIAxes = uiaxes(app.UIFigure);
            title(app.OverlayUIAxes, 'Overlay Result')
            app.OverlayUIAxes.XTick = [];
            app.OverlayUIAxes.YTick = [];
            app.OverlayUIAxes.Toolbar = [];
            app.OtsuUIAxes = uiaxes(app.UIFigure);
            title(app.OtsuUIAxes, 'Otsu')
            app.OtsuUIAxes.XTick = [];
            app.OtsuUIAxes.YTick = [];
            app.OtsuUIAxes.Toolbar = [];
            app.OtsuUIAxes.Visible = 'off';
            app.SauvolaUIAxes = uiaxes(app.UIFigure);
            title(app.SauvolaUIAxes, 'Sauvola')
            app.SauvolaUIAxes.XTick = [];
            app.SauvolaUIAxes.YTick = [];
            app.SauvolaUIAxes.Toolbar = [];
            app.SauvolaUIAxes.Visible = 'off';
            app.CannyUIAxes = uiaxes(app.UIFigure);
            title(app.CannyUIAxes, 'Canny')
            app.CannyUIAxes.XTick = [];
            app.CannyUIAxes.YTick = [];
            app.CannyUIAxes.Toolbar = [];
            app.CannyUIAxes.Visible = 'off';
            app.UNetUIAxes = uiaxes(app.UIFigure);
            title(app.UNetUIAxes, 'U-Net')
            app.UNetUIAxes.XTick = [];
            app.UNetUIAxes.YTick = [];
            app.UNetUIAxes.ZTick = [];
            app.UNetUIAxes.Toolbar = [];
            app.UNetUIAxes.Visible = 'off';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control Panel';
            app.DetectionMethodDropDownLabel = uilabel(app.ControlPanel);
            app.DetectionMethodDropDownLabel.Text = 'Detection Method';
            app.DetectionMethodDropDown = uidropdown(app.ControlPanel);
            app.DetectionMethodDropDown.Items = {'Otsu', 'Sauvola', 'Canny', 'U-Net'};
            app.DetectionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @DetectionMethodDropDownValueChanged, true);
            app.DetectionMethodDropDown.Value = 'Otsu';
            app.ApplyPostprocessingCheckBox = uicheckbox(app.ControlPanel);
            app.ApplyPostprocessingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplyPostprocessingCheckBoxValueChanged, true);
            app.ApplyPostprocessingCheckBox.Text = 'Apply Post-processing';
            app.RunDetectionButton = uibutton(app.ControlPanel, 'push');
            app.RunDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @RunDetectionButtonPushed, true);
            app.RunDetectionButton.Text = 'Run Detection';
            app.SaveResultsButton = uibutton(app.ControlPanel, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.StatusLabel = uilabel(app.ControlPanel);
            app.StatusLabel.Text = 'Ready';
            app.StatusLamp = uilamp(app.ControlPanel);
            app.DetectionModeButtonGroup = uibuttongroup(app.ControlPanel);
            app.DetectionModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DetectionModeButtonGroupSelectionChanged, true);
            app.DetectionModeButtonGroup.Title = 'Detection Mode';
            app.SingleButton = uitogglebutton(app.DetectionModeButtonGroup);
            app.SingleButton.Text = 'Single';
            app.SingleButton.Value = true;
            app.CompareButton = uitogglebutton(app.DetectionModeButtonGroup);
            app.CompareButton.Text = 'Compare';
            app.BatchButton = uitogglebutton(app.DetectionModeButtonGroup);
            app.BatchButton.Text = 'Batch';
            app.ClearButton = uibutton(app.ControlPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.LoadImageButton = uibutton(app.ControlPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.ResultViewDropDownLabel = uilabel(app.ControlPanel);
            app.ResultViewDropDownLabel.Visible = 'off';
            app.ResultViewDropDownLabel.Text = 'Result View';
            app.CompareViewDropDown = uidropdown(app.ControlPanel);
            app.CompareViewDropDown.Items = {'Mask', 'Overlay', 'Error Map'};
            app.CompareViewDropDown.ValueChangedFcn = createCallbackFcn(app, @CompareViewDropDownValueChanged, true);
            app.CompareViewDropDown.Visible = 'off';
            app.CompareViewDropDown.Value = 'Mask';
            app.BatchImageFolderEditFieldLabel = uilabel(app.ControlPanel);
            app.BatchImageFolderEditFieldLabel.Visible = 'off';
            app.BatchImageFolderEditFieldLabel.Text = 'Image Folder';
            app.BatchImageFolderEditField = uieditfield(app.ControlPanel, 'text');
            app.BatchImageFolderEditField.Editable = 'off';
            app.BatchImageFolderEditField.Visible = 'off';
            app.BrowseBatchImagesButton = uibutton(app.ControlPanel, 'push');
            app.BrowseBatchImagesButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseBatchImagesButtonPushed, true);
            app.BrowseBatchImagesButton.Visible = 'off';
            app.BrowseBatchImagesButton.Text = '...';
            app.RunBatchButton = uibutton(app.ControlPanel, 'push');
            app.RunBatchButton.ButtonPushedFcn = createCallbackFcn(app, @RunBatchButtonPushed, true);
            app.RunBatchButton.Visible = 'off';
            app.RunBatchButton.Text = 'Run Batch';
            app.BatchStatusLabel = uilabel(app.ControlPanel);
            app.BatchStatusLabel.Visible = 'off';
            app.BatchStatusLabel.Text = 'No batch selected';
            app.OutputFolderLabel = uilabel(app.ControlPanel);
            app.OutputFolderLabel.Visible = 'off';
            app.OutputFolderLabel.Text = 'Output Folder';
            app.BatchOutputFolderEditField = uieditfield(app.ControlPanel, 'text');
            app.BatchOutputFolderEditField.Editable = 'off';
            app.BatchOutputFolderEditField.Visible = 'off';
            app.BrowseBatchOutputButton = uibutton(app.ControlPanel, 'push');
            app.BrowseBatchOutputButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseBatchOutputButtonPushed, true);
            app.BrowseBatchOutputButton.Visible = 'off';
            app.BrowseBatchOutputButton.Text = '...';
            app.GTFolderLabel = uilabel(app.ControlPanel);
            app.GTFolderLabel.Visible = 'off';
            app.GTFolderLabel.Text = 'GT Folder';
            app.BatchGTFolderEditField = uieditfield(app.ControlPanel, 'text');
            app.BatchGTFolderEditField.Editable = 'off';
            app.BatchGTFolderEditField.Visible = 'off';
            app.BrowseBatchGTButton = uibutton(app.ControlPanel, 'push');
            app.BrowseBatchGTButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseBatchGTButtonPushed, true);
            app.BrowseBatchGTButton.Visible = 'off';
            app.BrowseBatchGTButton.Text = '...';
            app.EvaluationPanel = uipanel(app.UIFigure);
            app.EvaluationPanel.Title = 'Evaluation';
            app.IoULabel = uilabel(app.EvaluationPanel);
            app.IoULabel.Text = 'IoU';
            app.PrecisionLabel = uilabel(app.EvaluationPanel);
            app.PrecisionLabel.Text = 'Precision';
            app.RecallLabel = uilabel(app.EvaluationPanel);
            app.RecallLabel.Text = 'Recall';
            app.F1scoreLabel = uilabel(app.EvaluationPanel);
            app.F1scoreLabel.Text = 'F1-score';
            app.AccuracyLabel = uilabel(app.EvaluationPanel);
            app.AccuracyLabel.Text = 'Accuracy';
            app.TimesLabel = uilabel(app.EvaluationPanel);
            app.TimesLabel.Text = 'Time (s)';
            app.IoUEditField = uieditfield(app.EvaluationPanel, 'numeric');
            app.IoUEditField.ValueDisplayFormat = '%.3f';
            app.IoUEditField.Editable = 'off';
            app.PrecisionEditField = uieditfield(app.EvaluationPanel, 'numeric');
            app.PrecisionEditField.ValueDisplayFormat = '%.3f';
            app.PrecisionEditField.Editable = 'off';
            app.RecallEditField = uieditfield(app.EvaluationPanel, 'numeric');
            app.RecallEditField.ValueDisplayFormat = '%.3f';
            app.RecallEditField.Editable = 'off';
            app.F1EditField = uieditfield(app.EvaluationPanel, 'numeric');
            app.F1EditField.ValueDisplayFormat = '%.3f';
            app.F1EditField.Editable = 'off';
            app.AccuracyEditField = uieditfield(app.EvaluationPanel, 'numeric');
            app.AccuracyEditField.ValueDisplayFormat = '%.3f';
            app.AccuracyEditField.Editable = 'off';
            app.TimeEditField = uieditfield(app.EvaluationPanel, 'numeric');
            app.TimeEditField.ValueDisplayFormat = '%.3f';
            app.TimeEditField.Editable = 'off';
            app.MetricsStatusLabel = uilabel(app.EvaluationPanel);
            app.MetricsStatusLabel.Text = 'Metrics for Final Result';
            app.ComparisonUITable = uitable(app.UIFigure);
            app.ComparisonUITable.ColumnName = {'Method'; 'Precision'; 'Recall'; 'F1'; 'IoU'; 'Time (s)'};
            app.ComparisonUITable.RowName = {};
            app.BestValueNoteLabel = uilabel(app.UIFigure);
            app.BestValueNoteLabel.Visible = 'off';
            app.BestValueNoteLabel.Text = 'Red values indicate the best result in each column.';
            app.ErrorMapLegendLabel = uilabel(app.UIFigure);
            app.ErrorMapLegendLabel.Visible = 'off';
            app.ErrorMapLegendLabel.Text = 'Error Map: Green = TP, Red = FP, Blue = FN';
            app.BatchTitleLabel = uilabel(app.UIFigure);
            app.BatchTitleLabel.Visible = 'off';
            app.BatchTitleLabel.Text = 'Batch Processing';
            app.BatchSummaryUITable = uitable(app.UIFigure);
            app.BatchSummaryUITable.ColumnName = {'Method'; 'Images'; 'With GT'; 'Mean Precision'; 'Mean Recall'; 'Mean F1'; 'Mean IoU'; 'IoU Std'; 'Mean Time (s)'; 'Failures'};
            app.BatchSummaryUITable.RowName = {};
            app.BatchSummaryUITable.Visible = 'off';
            app.BatchResultsUITable = uitable(app.UIFigure);
            app.BatchResultsUITable.ColumnName = {'Image'; 'Method'; 'Precision'; 'Recall'; 'F1'; 'IoU'; 'Time (s)'; 'Status'};
            app.BatchResultsUITable.RowName = {};
            app.BatchResultsUITable.DoubleClickedFcn = createCallbackFcn(app, @BatchResultsUITableDoubleClicked, true);
            app.BatchResultsUITable.Visible = 'off';
            app.BatchBestValueNoteLabel = uilabel(app.UIFigure);
            app.BatchBestValueNoteLabel.Visible = 'off';
            app.BatchBestValueNoteLabel.Text = 'Red values indicate the best result in each column.';
            app.BatchIoUDistributionLabel = uilabel(app.UIFigure);
            app.BatchIoUDistributionLabel.Visible = 'off';
            app.BatchIoUDistributionLabel.Text = 'IoU Distribution — Number of Images';
            app.BatchIoUDistributionUITable = uitable(app.UIFigure);
            app.BatchIoUDistributionUITable.ColumnName = {'Method'; '0-0.1'; '0.1–0.2'; '0.2–0.3'; '0.3–0.4'; '0.4–0.5'; '0.5–0.6'; '0.6–0.7'; '0.7–0.8'; '0.8–0.9'; '0.9–1'};
            app.BatchIoUDistributionUITable.RowName = {};
            app.BatchIoUDistributionUITable.Visible = 'off';
            app.BatchResultHighlightNoteLabel = uilabel(app.UIFigure);
            app.BatchResultHighlightNoteLabel.Visible = 'off';
            app.BatchResultHighlightNoteLabel.Text = 'Double-click a row to view the image. Red = best value; Blue = worst value in each column.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoadCrackDetectionApp_v3_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end